classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        PushTool                  matlab.ui.container.toolbar.PushTool
        PushTool2                 matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        infoArea                  matlab.ui.control.TextArea
        Label                     matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        CheckBox                  matlab.ui.control.CheckBox
        autoLinkSubsystemPortBtn  matlab.ui.control.Button
        Button51                  matlab.ui.control.Button
        Button50                  matlab.ui.control.Button
        Button49                  matlab.ui.control.Button
        Button48                  matlab.ui.control.Button
        Button47                  matlab.ui.control.Button
        Button46                  matlab.ui.control.Button
        Button45                  matlab.ui.control.Button
        Button44                  matlab.ui.control.Button
        Button43                  matlab.ui.control.Button
        Button42                  matlab.ui.control.Button
        Button41                  matlab.ui.control.Button
        Button40                  matlab.ui.control.Button
        Button39                  matlab.ui.control.Button
        Button38                  matlab.ui.control.Button
        Button37                  matlab.ui.control.Button
        Button36                  matlab.ui.control.Button
        Button35                  matlab.ui.control.Button
        Button34                  matlab.ui.control.Button
        Button33                  matlab.ui.control.Button
        Button32                  matlab.ui.control.Button
        Button31                  matlab.ui.control.Button
        Button30                  matlab.ui.control.Button
        Button29                  matlab.ui.control.Button
        Button28                  matlab.ui.control.Button
        Button27                  matlab.ui.control.Button
        Button26                  matlab.ui.control.Button
        Button25                  matlab.ui.control.Button
        Button24                  matlab.ui.control.Button
        Button23                  matlab.ui.control.Button
        Button22                  matlab.ui.control.Button
        Button21                  matlab.ui.control.Button
        Button20                  matlab.ui.control.Button
        Button19                  matlab.ui.control.Button
        Button18                  matlab.ui.control.Button
        Button17                  matlab.ui.control.Button
        Button16                  matlab.ui.control.Button
        Button15                  matlab.ui.control.Button
        Button14                  matlab.ui.control.Button
        Button13                  matlab.ui.control.Button
        Button12                  matlab.ui.control.Button
        Button11                  matlab.ui.control.Button
        Button10                  matlab.ui.control.Button
        clearSignalNamesBtn       matlab.ui.control.Button
        lineSetName               matlab.ui.control.Button
        printModelParam           matlab.ui.control.Button
        CreateSubSystemPortBtn    matlab.ui.control.Button
        showModelTypeButton       matlab.ui.control.Button
        CreateOutputButton        matlab.ui.control.Button
        CreateInputButton         matlab.ui.control.Button
        setsignal_name            matlab.ui.control.Button
        bindSimulinksignal        matlab.ui.control.Button
        Tab                       matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        Button                    matlab.ui.control.Button
        Spinner                   matlab.ui.control.Spinner
        UITable                   matlab.ui.control.Table
        Tab_3                     matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        getBlockSpace             matlab.ui.control.Button
        space_number              matlab.ui.control.NumericEditField
        autoArangeVehBySpace      matlab.ui.control.Button
        Tab_4                     matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        UITable2                  matlab.ui.control.Table
        DropDown_2                matlab.ui.control.DropDown
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
    end


    properties (Access = private)
        curr_path=''; % Description
        curr_gcs='';
        original_path = {};              % 存储原始路径
        base_path = '';      % 动态获取的基础路径
       
    end
    
    properties (Access = public)
        altKey=false % Description
    end

    methods (Access = private)

        function results = removeAppPaths(app)
            % 生成所有需要移除的路径
            pathsToRemove = genpath(app.base_path);

            % 直接移除整个路径树
            rmpath(pathsToRemove);
            fprintf('已完全移除路径: %s\n', strrep(pathsToRemove, pathsep, '\n'));
            results=1;
        end

        function results = updateUIStatus(app, status, message)
            arguments
                app
                status (1,1) string {mustBeMember(status, ["success", "error", "warning"])}
                message (1,1) string
            end

            app.infoArea.Value = message;
            app.Label.Text = status;

            switch status
                case "success"
                    app.Label.BackgroundColor = [0.8 1 0.8]; % 浅绿色
                case "warning"
                    app.Label.BackgroundColor = [1 1 0.8];   % 浅黄色
                case "error"
                    app.Label.BackgroundColor = [1 0.8 0.8]; % 浅红色
            end
        end
        
        function results = handleError(app, ME, defaultMsg)
            arguments
                app
                ME (1,1) MException
                defaultMsg (1,1) string = "操作失败"
            end
            
            errMsg = ME.message;
            % 获取异常原因链
            if ~isempty(ME.cause)
                for i = 1:numel(ME.cause)
                    errMsg = errMsg + newline + "→ " + ME.cause{i}.message;
                end
            end
            
            % 特殊处理特定错误类型
            switch ME.identifier
                case 'NameTool:NoModel'
                    finalMsg = "错误：未打开Simulink模型";
                otherwise
                    finalMsg = defaultMsg + newline + errMsg;
            end
            
            app.updateUIStatus("error", finalMsg);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % 动态获取当前app文件所在目录路径
                app.base_path = fileparts(mfilename('fullpath'));
                fprintf('应用启动路径: %s\n', app.base_path);

                % 确保基本路径存在
                if ~isfolder(app.base_path)
                    error('基础路径不存在: %s', app.base_path);
                end

                % 添加当前目录及其所有子目录
                allPaths = genpath(app.base_path);
                addpath(allPaths);
                fprintf('已添加路径及其所有子目录:\n%s\n', allPaths);

            catch ME
                fprintf('路径添加失败: %s\n', ME.message);
            end
        end

        % Button pushed function: setsignal_name
        function setsignal_nameBtnCallFun(app, event)
            try
                if isempty(find_system('type', 'block_diagram'))
                    error('NameTool:NoModel', '未打开Simulink模型');
                end

                [successCount, signalNames] = setNameToSelectedBlocks();

                % 构建结果显示字符串
                if isempty(signalNames)
                    resultMsg = '没有信号线被处理';
                else
                    % 创建信号线列表
                    listHeader = sprintf('已处理 %d 个信号线:\n\n', successCount);
                    signalList = strjoin(signalNames, newline);
                    resultMsg = [listHeader signalList];
                end
            
                % 更新UI显示
                app.updateUIStatus("success", resultMsg);

            catch ME
                app.handleError(ME, "信号线赋值失败");
                % 更新UI显示错误

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 移除所有添加的路径
            app.removeAppPaths();

            delete(app)

        end

        % Button pushed function: bindSimulinksignal
        function bindSimulinksignalBtnCallFun(app, event)
            try
                % 调用处理函数并获取处理结果
                processedSignals = setSignalResolve();

                % 刷新模型确保更改生效
                set_param(gcs, 'Dirty', 'on');

                % 构建结果消息
                if isempty(processedSignals)
                    resultMsg = '未处理任何信号线（无有效源端口）';
                    color = [1 0.8 0.8]; % 浅红色
                    status = '警告';
                else
                    % 创建带有序号的信号列表
                    listItems = arrayfun(@(i)sprintf('%d. %s', i, processedSignals{i}), ...
                        1:length(processedSignals), 'UniformOutput', false);

                    resultMsg = sprintf('已成功设置 %d 条信号线:\n%s', ...
                        length(processedSignals), strjoin(listItems, newline));
                end

                % 更新UI显示
                app.updateUIStatus("success", resultMsg);

            catch ME
                 app.handleError(ME, "信号线绑定失败");
            end
        end

        % Button pushed function: autoArangeVehBySpace
        function autoArangeVehBySpaceBtnCallFunc(app, event)
            try
                autoArrangeSelectedBlocks(app.space_number.Value);
                 app.updateUIStatus("success", "模块自动排列完成");
            catch ME
                app.handleError(ME, "自动排列模块失败");
            end
        end

        % Button pushed function: getBlockSpace
        function getBlockSpaceBtnCallFunc(app, event)
            try
                [vDist, hDist] = calculateBlockDistances();
                info = sprintf('垂直: %.1f, 水平: %.1f\n', vDist, hDist);
                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "获取端口间距信息失败");
            end


        end

        % Button pushed function: CreateInputButton
        function CreateInputBtnCallFunc(app, event)
            try
                % 创建模块信息字符串
                addedBlockNames = addInputsFromClipboard('In1');
                info = '';
                if ~isempty(addedBlockNames)
                    info = sprintf('新添加 %d 个输入端口:\n', numel(addedBlockNames));
                    for i = 1:numel(addedBlockNames)
                        % 提取简单名称（去掉模型名前缀）
                        parts = strsplit(addedBlockNames{i}, '/');
                        simpleName = parts{end};
                        info = [info, sprintf('  %d: %s\n', i, simpleName)];
                    end
                else
                    info = '未添加任何输入端口 (剪贴板内容为空)';
                end

                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "自动添加Input模块失败");
            end
        end

        % Button pushed function: CreateOutputButton
        function CreateOutputBtnCallFunc(app, event)
            try
                % 创建模块信息字符串
                addedBlockNames = addInputsFromClipboard('Out1');
                info = '';
                if ~isempty(addedBlockNames)
                    info = sprintf('新添加 %d 个输出端口:\n', numel(addedBlockNames));
                    for i = 1:numel(addedBlockNames)
                        % 提取简单名称（去掉模型名前缀）
                        parts = strsplit(addedBlockNames{i}, '/');
                        simpleName = parts{end};
                        info = [info, sprintf('  %d: %s\n', i, simpleName)];
                    end
                else
                    info = '未添加任何输入端口 (剪贴板内容为空)';
                end

                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "自动添加Output模块失败");
            end
        end

        % Button pushed function: showModelTypeButton
        function showModelTypeBtn(app, event)
            try
                bk=find_system(gcs,'type','block', 'Selected', 'on');
                block_handle = get_param(bk{1}, 'Handle');
    
                % 打开属性检查器
                inspect(block_handle);
                app.updateUIStatus("success", "打开该模块的属性检查器成功。");
             catch ME
                app.handleError(ME, "打开该模块的属性检查器失败了。");
            end
        end

        % Button pushed function: CreateSubSystemPortBtn
        function CreateSubSystemPortBtnCallFun(app, event)
            try
                % 创建模块信息字符串
                autoCreateInOutPorts();
                info = '创建子系统的输入输出端口成功。';
                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "创建子系统的输入输出端口失败了。");
            end       
        end

        % Button pushed function: printModelParam
        function printModelParamCallFun(app, event)
            try
                % 创建模块信息字符串
                get(gcbh)
                info = '已经打印信息在控制台';
                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "打印信息在控制台失败了。");
            end
            
            %get_param(gcbh, 'ObjectParameters')
        end

        % Button pushed function: autoLinkSubsystemPortBtn
        function autoLinkSubsystemPortBtnCallFunc(app, event)
             try
                % 创建模块信息字符串
                app.CheckBox.Value
                autoLinkSubsystemPort(app.CheckBox.Value);
                info = '自动连接子系统端口成功。';
                % 更新UI显示
                app.updateUIStatus("success", info);
            catch ME
                app.handleError(ME, "自动连接子系统端口失败了。");
            end       
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

             
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;

        end

        % Button pushed function: lineSetName
        function lineSetNameCallFunc(app, event)
            autoNameSignalLines();
        end

        % Button pushed function: clearSignalNamesBtn
        function clearSignalNamesCallFun(app, event)
            clearSignalNames();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = 'icon1.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon', 'icon4.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '测试工具';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.bindSimulinksignal = uibutton(app.GridLayout2, 'push');
            app.bindSimulinksignal.ButtonPushedFcn = createCallbackFcn(app, @bindSimulinksignalBtnCallFun, true);
            app.bindSimulinksignal.Icon = fullfile(pathToMLAPP, 'icon', 'icon6.png');
            app.bindSimulinksignal.Tooltip = {'自动勾选信号线上的选型：信号名称必须解析为 Simulink 信号对象'};
            app.bindSimulinksignal.Text = '绑定变量';
            app.setsignal_name = uibutton(app.GridLayout2, 'push');
            app.setsignal_name.ButtonPushedFcn = createCallbackFcn(app, @setsignal_nameBtnCallFun, true);
            app.setsignal_name.Icon = fullfile(pathToMLAPP, 'icon', 'icon5.png');
            app.setsignal_name.Tooltip = {'自动批量对当前选中的输入输出模块的名字赋值到与其相连的信号线上。'};
            app.setsignal_name.Text = '信号线赋值';
            app.CreateInputButton = uibutton(app.GridLayout2, 'push');
            app.CreateInputButton.ButtonPushedFcn = createCallbackFcn(app, @CreateInputBtnCallFunc, true);
            app.CreateInputButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon1.png');
            app.CreateInputButton.Text = '创建Input';
            app.CreateOutputButton = uibutton(app.GridLayout2, 'push');
            app.CreateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputBtnCallFunc, true);
            app.CreateOutputButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon4.png');
            app.CreateOutputButton.Text = '创建Output';
            app.showModelTypeButton = uibutton(app.GridLayout2, 'push');
            app.showModelTypeButton.ButtonPushedFcn = createCallbackFcn(app, @showModelTypeBtn, true);
            app.showModelTypeButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon3.png');
            app.showModelTypeButton.Text = '模块属性查看器';
            app.CreateSubSystemPortBtn = uibutton(app.GridLayout2, 'push');
            app.CreateSubSystemPortBtn.ButtonPushedFcn = createCallbackFcn(app, @CreateSubSystemPortBtnCallFun, true);
            app.CreateSubSystemPortBtn.Icon = fullfile(pathToMLAPP, 'icon', 'icon4.png');
            app.CreateSubSystemPortBtn.Text = '创建子系统端口';
            app.printModelParam = uibutton(app.GridLayout2, 'push');
            app.printModelParam.ButtonPushedFcn = createCallbackFcn(app, @printModelParamCallFun, true);
            app.printModelParam.Icon = fullfile(pathToMLAPP, 'icon', 'icon2.png');
            app.printModelParam.Text = '打印模块属性';
            app.lineSetName = uibutton(app.GridLayout2, 'push');
            app.lineSetName.ButtonPushedFcn = createCallbackFcn(app, @lineSetNameCallFunc, true);
            app.lineSetName.Icon = fullfile(pathToMLAPP, 'icon', 'icon5.png');
            app.lineSetName.Text = '信号线命名';
            app.clearSignalNamesBtn = uibutton(app.GridLayout2, 'push');
            app.clearSignalNamesBtn.ButtonPushedFcn = createCallbackFcn(app, @clearSignalNamesCallFun, true);
            app.clearSignalNamesBtn.Icon = fullfile(pathToMLAPP, 'icon', 'icon1.png');
            app.clearSignalNamesBtn.Text = '清除信号线名称';
            app.Button10 = uibutton(app.GridLayout2, 'push');
            app.Button10.Text = 'Button10';
            app.Button11 = uibutton(app.GridLayout2, 'push');
            app.Button11.Text = 'Button11';
            app.Button12 = uibutton(app.GridLayout2, 'push');
            app.Button12.Text = 'Button12';
            app.Button13 = uibutton(app.GridLayout2, 'push');
            app.Button13.Text = 'Button13';
            app.Button14 = uibutton(app.GridLayout2, 'push');
            app.Button14.Text = 'Button14';
            app.Button15 = uibutton(app.GridLayout2, 'push');
            app.Button15.Text = 'Button15';
            app.Button16 = uibutton(app.GridLayout2, 'push');
            app.Button16.Text = 'Button16';
            app.Button17 = uibutton(app.GridLayout2, 'push');
            app.Button17.Text = 'Button17';
            app.Button18 = uibutton(app.GridLayout2, 'push');
            app.Button18.Text = 'Button18';
            app.Button19 = uibutton(app.GridLayout2, 'push');
            app.Button19.Text = 'Button19';
            app.Button20 = uibutton(app.GridLayout2, 'push');
            app.Button20.Text = 'Button20';
            app.Button21 = uibutton(app.GridLayout2, 'push');
            app.Button21.Text = 'Button21';
            app.Button22 = uibutton(app.GridLayout2, 'push');
            app.Button22.Text = 'Button22';
            app.Button23 = uibutton(app.GridLayout2, 'push');
            app.Button23.Text = 'Button23';
            app.Button24 = uibutton(app.GridLayout2, 'push');
            app.Button24.Text = 'Button24';
            app.Button25 = uibutton(app.GridLayout2, 'push');
            app.Button25.Text = 'Button25';
            app.Button26 = uibutton(app.GridLayout2, 'push');
            app.Button26.Text = 'Button26';
            app.Button27 = uibutton(app.GridLayout2, 'push');
            app.Button27.Text = 'Button27';
            app.Button28 = uibutton(app.GridLayout2, 'push');
            app.Button28.Text = 'Button28';
            app.Button29 = uibutton(app.GridLayout2, 'push');
            app.Button29.Text = 'Button29';
            app.Button30 = uibutton(app.GridLayout2, 'push');
            app.Button30.Text = 'Button30';
            app.Button31 = uibutton(app.GridLayout2, 'push');
            app.Button31.Text = 'Button31';
            app.Button32 = uibutton(app.GridLayout2, 'push');
            app.Button32.Text = 'Button32';
            app.Button33 = uibutton(app.GridLayout2, 'push');
            app.Button33.Text = 'Button33';
            app.Button34 = uibutton(app.GridLayout2, 'push');
            app.Button34.Text = 'Button34';
            app.Button35 = uibutton(app.GridLayout2, 'push');
            app.Button35.Text = 'Button35';
            app.Button36 = uibutton(app.GridLayout2, 'push');
            app.Button36.Text = 'Button36';
            app.Button37 = uibutton(app.GridLayout2, 'push');
            app.Button37.Text = 'Button37';
            app.Button38 = uibutton(app.GridLayout2, 'push');
            app.Button38.Text = 'Button38';
            app.Button39 = uibutton(app.GridLayout2, 'push');
            app.Button39.Text = 'Button39';
            app.Button40 = uibutton(app.GridLayout2, 'push');
            app.Button40.Text = 'Button40';
            app.Button41 = uibutton(app.GridLayout2, 'push');
            app.Button41.Text = 'Button41';
            app.Button42 = uibutton(app.GridLayout2, 'push');
            app.Button42.Text = 'Button42';
            app.Button43 = uibutton(app.GridLayout2, 'push');
            app.Button43.Text = 'Button43';
            app.Button44 = uibutton(app.GridLayout2, 'push');
            app.Button44.Text = 'Button44';
            app.Button45 = uibutton(app.GridLayout2, 'push');
            app.Button45.Text = 'Button45';
            app.Button46 = uibutton(app.GridLayout2, 'push');
            app.Button46.Text = 'Button46';
            app.Button47 = uibutton(app.GridLayout2, 'push');
            app.Button47.Text = 'Button47';
            app.Button48 = uibutton(app.GridLayout2, 'push');
            app.Button48.Text = 'Button48';
            app.Button49 = uibutton(app.GridLayout2, 'push');
            app.Button49.Text = 'Button49';
            app.Button50 = uibutton(app.GridLayout2, 'push');
            app.Button50.Text = 'Button50';
            app.Button51 = uibutton(app.GridLayout2, 'push');
            app.Button51.Text = 'Button51';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.autoLinkSubsystemPortBtn = uibutton(app.GridLayout7, 'push');
            app.autoLinkSubsystemPortBtn.ButtonPushedFcn = createCallbackFcn(app, @autoLinkSubsystemPortBtnCallFunc, true);
            app.autoLinkSubsystemPortBtn.Icon = fullfile(pathToMLAPP, 'icon', 'icon4.png');
            app.autoLinkSubsystemPortBtn.Text = '自动连线';
            app.CheckBox = uicheckbox(app.GridLayout7);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '批量工具';
            app.GridLayout3 = uigridlayout(app.Tab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'A'; 'B'; 'C'; 'D'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.Spinner = uispinner(app.GridLayout3);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.Value = 1;
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.Tooltip = {'按深度遍历输入输出模块'};
            app.Button.Text = '遍历模块';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '带参工具';
            app.GridLayout4 = uigridlayout(app.Tab_3);
            app.GridLayout4.Tag = 'space_number';
            app.GridLayout4.Tooltip = {'上下距离单位为像素'};
            app.autoArangeVehBySpace = uibutton(app.GridLayout4, 'push');
            app.autoArangeVehBySpace.ButtonPushedFcn = createCallbackFcn(app, @autoArangeVehBySpaceBtnCallFunc, true);
            app.autoArangeVehBySpace.Tooltip = {'设置选中的模块按照指定垂直距离由上到下重新布置。'};
            app.autoArangeVehBySpace.Text = '垂直等距';
            app.space_number = uieditfield(app.GridLayout4, 'numeric');
            app.space_number.Limits = [0 Inf];
            app.space_number.RoundFractionalValues = 'on';
            app.space_number.Placeholder = '单位:像素';
            app.space_number.Value = 21;
            app.getBlockSpace = uibutton(app.GridLayout4, 'push');
            app.getBlockSpace.ButtonPushedFcn = createCallbackFcn(app, @getBlockSpaceBtnCallFunc, true);
            app.getBlockSpace.Text = '获取模块间距';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '数据生成';
            app.GridLayout6 = uigridlayout(app.Tab_4);
            app.DropDown = uidropdown(app.GridLayout6);
            app.DropDown.Items = {'Simulink.Parameter', 'Simulink.Signal', 'Simulink.NumericType'};
            app.DropDown.Value = 'Simulink.Parameter';
            app.Button_2 = uibutton(app.GridLayout6, 'push');
            app.Button_2.Text = '渲染模板';
            app.Button_3 = uibutton(app.GridLayout6, 'push');
            app.Button_3.Text = '生成数据';
            app.DropDown_2 = uidropdown(app.GridLayout6);
            app.DropDown_2.Items = {'工作区', 'Mat文件', 'M文件'};
            app.DropDown_2.Value = '工作区';
            app.UITable2 = uitable(app.GridLayout6);
            app.UITable2.ColumnName = {'A'; 'B'; 'C'; 'D'};
            app.UITable2.RowName = {};
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Tag = 'Label';
            app.Label.Text = '使用指南';
            app.infoArea = uitextarea(app.GridLayout5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end